classdef Frame12_Traening_med_livefeedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FeedbackLitterluftUIAxes        matlab.ui.control.UIAxes
        AfbrydFrame12Button             matlab.ui.control.Button
        PauseFrame12Button              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        NedtaellingAntalVejrT_TextArea  matlab.ui.control.TextArea
        NedtaellingAntalVejrtraekningEditField  matlab.ui.control.NumericEditField
        infoFrame12Button               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        callingApp % callingApp
        flows% Data of breath
        pressures 
        
        RR;
        C;
        MIP;
        duration;
        threshold;
        sF;
        amount;
        
        
    end
    
    methods (Access = private)
        
        function updateGraph(app,data)
            n = size(data,2);
            x = 1:n;
            stepsize = 50;
            
            for i=1:stepsize:n
                plot(app.FeedbackLitterluftUIAxes, x(1:i), data(1:i));
                drawnow;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, flows, pressures, RR, C, MIP, duration, threshold, sF, amount, modstand)
            delete(callingApp); % Lukker calling window 
            
            app.flows = flows;
            app.pressures = pressures;
                       
            app.RR = RR;
            app.C = C;
            app.MIP = MIP;
            app.duration=duration;
            app.threshold=threshold;
            app.sF=sF;
            app.amount = amount;
            
            app.FeedbackLitterluftUIAxes.YLim=([0 max(app.pressures(1,:))]);% Tilpasser akserne så de passer med inputdata
            %app.FeedbackLitterluftUIAxes.XLim=([0 length(pressures(1,:))/app.sF]); %tilpasser akserne så de passer med inputdata
            updateGraph(app, app.pressures(1,:));


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {440, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FeedbackLitterluftUIAxes = uiaxes(app.LeftPanel);
            title(app.FeedbackLitterluftUIAxes, 'Feedbackgraf')
            xlabel(app.FeedbackLitterluftUIAxes, 'Volumen (L)')
            ylabel(app.FeedbackLitterluftUIAxes, 'Flow (L/s)')
            app.AfbrydFrame12Button = uibutton(app.LeftPanel, 'push');
            app.AfbrydFrame12Button.Text = 'Afbryd';
            app.PauseFrame12Button = uibutton(app.LeftPanel, 'push');
            app.PauseFrame12Button.Text = 'Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.NedtaellingAntalVejrT_TextArea = uitextarea(app.RightPanel);
            app.NedtaellingAntalVejrT_TextArea.Value = {'Vejrtrækninger tilbage inden pause'};
            app.NedtaellingAntalVejrtraekningEditField = uieditfield(app.RightPanel, 'numeric');
            app.infoFrame12Button = uibutton(app.RightPanel, 'push');
            app.infoFrame12Button.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame12_Traening_med_livefeedback(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end