classdef Frame2_2vejrtraekninger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Introtekst2vejrtraekningerLabel  matlab.ui.control.Label
        AfbrydButton        matlab.ui.control.Button
        Image               matlab.ui.control.Image
        infoFrame2Button    matlab.ui.control.Button
        AntalvejrtrkningerregistreretEditFieldLabel  matlab.ui.control.Label
        AntalvejrtrkningerregistreretEditField  matlab.ui.control.NumericEditField
        FlotfortstterLabel  matlab.ui.control.Label
        PauseFrame12Button  matlab.ui.control.Button
    end

    
    properties (Access = private)
        breathData % breathData recieved from previous window
        callingApp % Calling app reference
        newWindow % new window to open: live training plot
    end
    
    methods (Access = private)
        
        function openNextWindow(app,data)
            app.newWindow = Frame12_Traening_med_livefeedback(app, data);
            
        end
    end
    
    methods (Access = public)
        
        function closeWindow(app)
            delete(app);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, breath)
            app.callingApp = callingApp;
            app.breathData = breath;

            
            [peak loc] = findpeaks(app.breathData);
            
            pause(2);
            for i = 1:size(loc,2)
                app.AntalvejrtrkningerregistreretEditField.Value = i;
                pause(1);
            end
            
            if((peak > 5000) & (size(peak,2) == 2))
                app.FlotfortstterLabel.Visible = 'on';
            end
            pause(2);
            
            openNextWindow(app,app.breathData);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Introtekst2vejrtraekningerLabel = uilabel(app.UIFigure);
            app.Introtekst2vejrtraekningerLabel.Text = {'Tag to dybe indåndinger, hvor du trækker '; 'vejret så dybt og hurtigt du kan.'; 'Start når du er klar!'; ''};
            app.AfbrydButton = uibutton(app.UIFigure, 'push');
            app.AfbrydButton.Text = 'Afbryd';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Vejrtraekning.png';
            app.infoFrame2Button = uibutton(app.UIFigure, 'push');
            app.infoFrame2Button.Text = 'Info';
            app.AntalvejrtrkningerregistreretEditFieldLabel = uilabel(app.UIFigure);
            app.AntalvejrtrkningerregistreretEditFieldLabel.Text = 'Antal vejrtrækninger registreret';
            app.AntalvejrtrkningerregistreretEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlotfortstterLabel = uilabel(app.UIFigure);
            app.FlotfortstterLabel.Visible = 'off';
            app.FlotfortstterLabel.Text = 'Flot! - fortsætter...';
            app.PauseFrame12Button = uibutton(app.UIFigure, 'push');
            app.PauseFrame12Button.Text = 'Spring modstansindstilling over';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Frame2_2vejrtraekninger(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end